classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Label              matlab.ui.control.Label
        PathLabel          matlab.ui.control.Label
        stopButton         matlab.ui.control.Button
        BrowsefilesButton  matlab.ui.control.Button
        ResumeButton       matlab.ui.control.Button
        Slider2            matlab.ui.control.Slider
        Slider2Label       matlab.ui.control.Label
        PlayButton         matlab.ui.control.Button
        Slider             matlab.ui.control.Slider
        SliderLabel        matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
        ContextMenu        matlab.ui.container.ContextMenu
        Menu               matlab.ui.container.Menu
        Menu_2             matlab.ui.container.Menu
        Menu2              matlab.ui.container.Menu
    end

    
    properties (Access = private)
        Samplerate = NaN;
        Audiodata = NaN;
        player = NaN;
        State_pause = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
          
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            try
                stop(app.player);
                play(app.player);
                app.State_pause = 0;
                app.ResumeButton.Text = "Pause";
            catch
                [y,Fs] = audioread("error sound.mp3");
                app.Samplerate = Fs;
                app.Audiodata = y;
                app.player = audioplayer(app.Audiodata ,app.Samplerate);
                stop(app.player);
                play(app.player);
            end
            
        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            value = app.Slider2.Value;
        end

        % Button pushed function: BrowsefilesButton
        function BrowsefilesButtonPushed(app, event)
            [filename,path] = uigetfile({'*.m4a;*.mp4;*.mp3;*.wav;*.opus;*.ogg;*.flac;*.au;*.aiff;*.aif;*.aifc'},'Select One Files');
            fullpath = [path filename];           
            try 
                fullpath == [0 0]
                app.Label.Text = "";
                [y,Fs] = audioread("error sound.mp3");
                app.Samplerate = Fs;
                app.Audiodata = y;
                app.player = audioplayer(app.Audiodata ,app.Samplerate);
            catch           
                app.Label.Text = fullpath;
                [y,Fs] = audioread(fullpath);
                app.Samplerate = Fs;
                app.Audiodata = y;
                app.player = audioplayer(app.Audiodata ,app.Samplerate);
            end
            app.State_pause = 1;
            app.ResumeButton.Text = "Resume";
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
              if app.State_pause == 0
                  pause(app.player);
                  app.State_pause = 1;
                  app.ResumeButton.Text = "Resume";
              else
                  try
                    resume(app.player);
                    app.State_pause = 0;
                    app.ResumeButton.Text = "Pause";
                  catch
                    [y,Fs] = audioread("error sound.mp3");
                    app.Samplerate = Fs;
                    app.Audiodata = y;
                    app.player = audioplayer(app.Audiodata ,app.Samplerate);
                    resume(app.player);
                  end
              end
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            try
                stop(app.player);
                app.State_pause = 1;
                app.ResumeButton.Text = "Resume";
            catch
                [y,Fs] = audioread("error sound.mp3");
                app.Samplerate = Fs;
                app.Audiodata = y;
                app.player = audioplayer(app.Audiodata ,app.Samplerate);
                play(app.player);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.Slider2Label = uilabel(app.UIFigure);
            app.Slider2Label.Text = 'Slider2';
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.BrowsefilesButton = uibutton(app.UIFigure, 'push');
            app.BrowsefilesButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsefilesButtonPushed, true);
            app.BrowsefilesButton.Text = 'Browse files';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.PathLabel = uilabel(app.UIFigure);
            app.PathLabel.Text = 'Path:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end